classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        ActivateButton          matlab.ui.control.StateButton
        ExportButton            matlab.ui.control.Button
        DistanceEditField       matlab.ui.control.NumericEditField
        DistanceEditFieldLabel  matlab.ui.control.Label
        COMPortEditField        matlab.ui.control.EditField
        COMPortEditFieldLabel   matlab.ui.control.Label
        BaudRateEditField       matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel  matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Distance =[];
        Time = [];
        Date =[];
                
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
           %msgbox(app.ExportTable,"Error");
        end

        % Value changed function: DistanceEditField
        function DistanceEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
           % Assuming app.Distance, app.Time, and app.Date are variables or arrays you want to export

            Dis = transpose(["Distance",app.Distance]);
            DisTime = transpose(["Time",app.Time]);
            DisDate = transpose(['yyyy-mm-dd_HH-MM-SS',app.Date]);
                
            
            % Concatenate data into a matrix
            combinedData = [num2cell([Dis, DisTime]), DisDate]; % Assuming expData is numeric or can be converted to cell array
           

            % Generate a dynamic filename based on current date and time
            currentDateTime = datestr(now, 'yyyy-mm-dd_HH-MM-SS'); 
            
            % Get the path of the currently executing file (app.exe)
            executablePath = fileparts(mfilename(strcat('fullpath','\for_redistribution_files_only\LiDAR_BYUI.exe\Data')));
            
            % Define the Excel file name
            excelFileName = ['LiDAR_', currentDateTime, '.csv'];
            
            % Full path to the Excel file
            excelFilePath = fullfile(executablePath, excelFileName);
            
            % Export data to Excel
            try
                writecell(combinedData, excelFilePath);
                disp(['Excel file saved successfully: ', excelFilePath]);
            catch ME
                disp(['Error saving Excel file: ', ME.message]);
                msgbox("Error saving Excel file",ME.message);
            end

         
        end

        % Value changed function: ActivateButton
        function ActivateButtonValueChanged(app, event)
            value = app.ActivateButton.Value;
            Com_Port = string(app.COMPortEditField.Value); %needs to be a string scalar
            Baud_Rate = app.BaudRateEditField.Value; % needs to be a double
            

            if (value==1) % If the button is down (activated)
                tic;
                
                try
                    device = serialport(string(Com_Port),Baud_Rate);
                    Dist = app.DistanceEditField.Value; % if the value changes store it into a table, and update chart
                    while (app.ActivateButton.Value)
                   
                        
                        app.DistanceEditField.Value = str2double(readline(device)); % reads 1 value 32 bits from device 
                        
                        
                        if(Dist ~= app.DistanceEditField.Value) % if the value is updated
                            Dist = app.DistanceEditField.Value; % update the value
                            d2 =  datestr(now, 'yyyy-mm-dd_HH-MM-SS'); % Get Date Information
                
                            app.Distance(end+1) = app.DistanceEditField.Value; %Add Distance to an array
                            app.Time(end+1) = toc; %add time since start
                            app.Date{end+1} = d2; %add date time


                            % Update the plot
                            plot(app.UIAxes, app.Time, app.Distance);
                            xlabel(app.UIAxes, 'Elapsed Time');
                            ylabel(app.UIAxes, 'Distance (m)');
                            title(app.UIAxes, 'LiDAR Recorded Distances over Time');
                        end
                        
                        
                    end
                  
                catch
                    app.ActivateButton.Value = 0;% push button up
                    msgbox("COM Port is wrong, check connection","Error"); % display error window
                end
                % Generate some data (example)
               
            else % if the button is up (deactivated)
                % do nothing
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Measured Distances Over Time')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Distance (m)')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'byui logo.png';
            app.BaudRateEditFieldLabel = uilabel(app.Panel);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.Panel, 'numeric');
            app.BaudRateEditField.Value = 115200;
            app.COMPortEditFieldLabel = uilabel(app.Panel);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.Panel, 'text');
            app.COMPortEditField.Value = 'COM3';
            app.DistanceEditFieldLabel = uilabel(app.Panel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.Panel, 'numeric');
            app.DistanceEditField.ValueChangedFcn = createCallbackFcn(app, @DistanceEditFieldValueChanged, true);
            app.ExportButton = uibutton(app.Panel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ActivateButton = uibutton(app.Panel, 'state');
            app.ActivateButton.ValueChangedFcn = createCallbackFcn(app, @ActivateButtonValueChanged, true);
            app.ActivateButton.Text = 'Activate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end